classdef Histoquant_with_ROI_drawing_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        remove_smallEditField         matlab.ui.control.NumericEditField
        remove_smallEditFieldLabel    matlab.ui.control.Label
        gray_thresholdEditField       matlab.ui.control.NumericEditField
        gray_thresholdEditFieldLabel  matlab.ui.control.Label
        subtractROIButton             matlab.ui.control.Button
        deleteallROIsButton           matlab.ui.control.Button
        gauss_filtEditField           matlab.ui.control.NumericEditField
        gauss_filtEditFieldLabel      matlab.ui.control.Label
        blueEditField                 matlab.ui.control.NumericEditField
        blueEditFieldLabel            matlab.ui.control.Label
        greenEditField                matlab.ui.control.NumericEditField
        greenEditFieldLabel           matlab.ui.control.Label
        redEditField                  matlab.ui.control.NumericEditField
        redEditFieldLabel             matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        addROIButton_2                matlab.ui.control.Button
        ParametersLabel               matlab.ui.control.Label
        LUTsLabel                     matlab.ui.control.Label
        CheckBox_3                    matlab.ui.control.CheckBox
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox                      matlab.ui.control.CheckBox
        EditField_3                   matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        RightPanel                    matlab.ui.container.Panel
        ResultsTextArea               matlab.ui.control.TextArea
        ResultsTextAreaLabel          matlab.ui.control.Label
        ImageEditField                matlab.ui.control.EditField
        ImageEditFieldLabel           matlab.ui.control.Label
        LoadimageButton               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)
        
        function display_image(app)
            global im_rgb roi_mask;
            
            Cr_min=app.redEditField.Value;
            Cr_max=app.EditField.Value;
            Cg_min=app.greenEditField.Value;
            Cg_max=app.EditField_2.Value;
            Cb_min=app.blueEditField.Value;
            Cb_max=app.EditField_3.Value;
            s=size(im_rgb);
            im_scaled=zeros(s(1),s(2),s(3),'single');
            if app.CheckBox.Value==1
                im_scaled(:,:,1)=(single(im_rgb(:,:,1))-Cr_min)/(Cr_max-Cr_min);
            end
            if app.CheckBox_2.Value==1
                im_scaled(:,:,2)=(single(im_rgb(:,:,2))-Cg_min)/(Cg_max-Cg_min)+single(roi_mask)*0.3;
            end
            if app.CheckBox_3.Value==1
                im_scaled(:,:,3)=(single(im_rgb(:,:,3))-Cb_min)/(Cb_max-Cb_min);
            end
            imshow(im_scaled,"Parent",app.UIAxes)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadImage(app, event)
            global im_rgb is_first impath roi_mask;
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [imfile,impath]=uigetfile(filterspec);
            app.ImageEditField.Value = imfile;       
            im_rgb=imread([impath,'\',imfile]);
           
            is_first=1;
            roi_mask=im_rgb(:,:,1)==-1;
            display_image(app);
        end

        % Value changed function: redEditField
        function Red_min(app, event)
            %value = app.redEditField.Value;
            display_image(app);
        end

        % Value changed function: EditField
        function Red_max(app, event)
            %value = app.EditField.Value;
            display_image(app);
        end

        % Value changed function: greenEditField
        function Green_min(app, event)
            %value = app.greenEditField.Value;
            display_image(app);
        end

        % Value changed function: EditField_2
        function Green_max(app, event)
            %value = app.EditField_2.Value;
            display_image(app);
        end

        % Value changed function: blueEditField
        function Blue_min(app, event)
            %value = app.blueEditField.Value;
            display_image(app);
        end

        % Value changed function: EditField_3
        function Blue_max(app, event)
            %value = app.EditField_3.Value;
            display_image(app);
        end

        % Value changed function: CheckBox
        function red_on(app, event)
            %value = app.CheckBox.Value;
            display_image(app);
        end

        % Value changed function: CheckBox_2
        function green_on(app, event)
            %value = app.CheckBox_2.Value;
            display_image(app);
        end

        % Value changed function: CheckBox_3
        function blue_on(app, event)
            %value = app.CheckBox_3.Value;
            display_image(app);
        end

        % Button pushed function: addROIButton_2
        function addROI(app, event)
            global roi_mask;
            roi=drawfreehand(app.UIAxes);
            roi_mask1=createMask(roi);
            roi_mask=or(roi_mask,roi_mask1);
            display_image(app);
        end

        % Button pushed function: CalculateButton
        function Calc(app, event)
            global im_rgb roi_mask is_first fileID impath;
            close all
             imm=im_rgb.*uint8(roi_mask);
%imm=im;

gray=im_rgb(:,:,1)/3+im_rgb(:,:,2)/3+im_rgb(:,:,3)/3;
gray=255-gray;
gray=imgaussfilt(gray,app.gauss_filtEditField.Value);
% figure
% imshow(gray.*uint8(roi_mask))

%T = graythresh(gray);% adaptthresh(gray, app.gray_thresholdEditField.Value);
%[BW,maskedimall] = createMask_annika_Bkdgsub(im_rgb);
BW = logical(imbinarize(gray,app.gray_thresholdEditField.Value).*roi_mask);
hbw=bwareaopen(~BW,app.remove_smallEditField.Value);
BW=~hbw;
maskedim=uint8(BW).*imm;
toquant=gray.*uint8(BW);

maskedimall=maskedim;
maskedimall(repmat(~BW,[1 1 3])) = 0;
close all
figure
imshow(maskedimall)
title('All segmented cells')
figure
imshow(toquant)
title('grayscale image (inverted) for quantification')

total_area=sum(sum(BW));
mean_int=sum(sum(toquant))/total_area;
            %fprintf('\nMeasured mean intensity in ROI = %2.3f \n',mean_int);
            app.ResultsTextArea.Value=['Measured mean intensity in ROI: ',num2str(mean_int,'%2.3f')];
 
        end

        % Button pushed function: deleteallROIsButton
        function deleteallROIs(app, event)
            global roi_mask im_rgb;
              roi_mask=im_rgb(:,:,1)==-1;
            display_image(app);
        end

        % Button pushed function: subtractROIButton
        function subtractROI(app, event)
                        global roi_mask;
            roi=drawfreehand(app.UIAxes);
            roi_mask1=createMask(roi);
            roi_maskxor=xor(roi_mask,roi_mask1);
            roi_mask=and(roi_mask,roi_maskxor);
            display_image(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {148, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @Red_max, true);
            app.EditField.Value = 255;
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @Green_max, true);
            app.EditField_2.Value = 255;
            app.EditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @Blue_max, true);
            app.EditField_3.Value = 255;
            app.CheckBox = uicheckbox(app.LeftPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @red_on, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.LeftPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @green_on, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.LeftPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @blue_on, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.LUTsLabel = uilabel(app.LeftPanel);
            app.LUTsLabel.Text = 'LUTs';
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.addROIButton_2 = uibutton(app.LeftPanel, 'push');
            app.addROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @addROI, true);
            app.addROIButton_2.Text = 'add ROI';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @Calc, true);
            app.CalculateButton.Text = 'Calculate';
            app.redEditFieldLabel = uilabel(app.LeftPanel);
            app.redEditFieldLabel.Text = 'red';
            app.redEditField = uieditfield(app.LeftPanel, 'numeric');
            app.redEditField.ValueChangedFcn = createCallbackFcn(app, @Red_min, true);
            app.greenEditFieldLabel = uilabel(app.LeftPanel);
            app.greenEditFieldLabel.Text = 'green';
            app.greenEditField = uieditfield(app.LeftPanel, 'numeric');
            app.greenEditField.ValueChangedFcn = createCallbackFcn(app, @Green_min, true);
            app.blueEditFieldLabel = uilabel(app.LeftPanel);
            app.blueEditFieldLabel.Text = 'blue';
            app.blueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.blueEditField.ValueChangedFcn = createCallbackFcn(app, @Blue_min, true);
            app.gauss_filtEditFieldLabel = uilabel(app.LeftPanel);
            app.gauss_filtEditFieldLabel.Text = 'gauss_filt';
            app.gauss_filtEditField = uieditfield(app.LeftPanel, 'numeric');
            app.gauss_filtEditField.Value = 1;
            app.deleteallROIsButton = uibutton(app.LeftPanel, 'push');
            app.deleteallROIsButton.ButtonPushedFcn = createCallbackFcn(app, @deleteallROIs, true);
            app.deleteallROIsButton.Text = 'delete all ROIs';
            app.subtractROIButton = uibutton(app.LeftPanel, 'push');
            app.subtractROIButton.ButtonPushedFcn = createCallbackFcn(app, @subtractROI, true);
            app.subtractROIButton.Text = 'subtract ROI';
            app.gray_thresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.gray_thresholdEditFieldLabel.Text = 'gray_threshold';
            app.gray_thresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.gray_thresholdEditField.Value = 0.25;
            app.remove_smallEditFieldLabel = uilabel(app.LeftPanel);
            app.remove_smallEditFieldLabel.Text = 'remove_small';
            app.remove_smallEditField = uieditfield(app.LeftPanel, 'numeric');
            app.remove_smallEditField.Value = 200;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.LoadimageButton = uibutton(app.RightPanel, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImage, true);
            app.LoadimageButton.Text = 'Load image';
            app.ImageEditFieldLabel = uilabel(app.RightPanel);
            app.ImageEditFieldLabel.Text = 'Image:';
            app.ImageEditField = uieditfield(app.RightPanel, 'text');
            app.ImageEditField.Value = 'image name';
            app.ResultsTextAreaLabel = uilabel(app.RightPanel);
            app.ResultsTextAreaLabel.Text = 'Results:';
            app.ResultsTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Histoquant_with_ROI_drawing_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end